classdef ImageFeaturesAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageFeatureAnalysisUIFigure  matlab.ui.Figure
        CataloguePanel                matlab.ui.container.Panel
        ExtensionDropDownLabel        matlab.ui.control.Label
        ExtensionDropDown             matlab.ui.control.DropDown
        ChooseonepathButton           matlab.ui.control.Button
        ChoosetwopathsButton          matlab.ui.control.Button
        ImagesPanel                   matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        PreviousButton                matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        stimageEditFieldLabel         matlab.ui.control.Label
        stimageEditField              matlab.ui.control.NumericEditField
        ndimageEditFieldLabel         matlab.ui.control.Label
        ndimageEditField              matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        OptionsPanel                  matlab.ui.container.Panel
        DescriptorsButtonGroup        matlab.ui.container.ButtonGroup
        SURFButton                    matlab.ui.control.ToggleButton
        BRISKButton                   matlab.ui.control.ToggleButton
        HOGButton                     matlab.ui.control.ToggleButton
        FASTButton                    matlab.ui.control.ToggleButton
        ORBButton                     matlab.ui.control.ToggleButton
        MSERButton                    matlab.ui.control.ToggleButton
        HARRISButton                  matlab.ui.control.ToggleButton
        ColormodelSwitchLabel         matlab.ui.control.Label
        ColormodelSwitch              matlab.ui.control.Switch
        SimilaritythresholdEditFieldLabel  matlab.ui.control.Label
        SimilaritythresholdEditField  matlab.ui.control.NumericEditField
        CompareButton                 matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        GeneratereportButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        numberOfFiles;                        %number of all files
        numberOfFiles2;                       %number of all files from 2nd path
        secondPathIsChoosed = false;          %indicates if 2nd path is choosed
        I;                                    %first image
        I2;                                   %second image
        IAll = struct('Image', []);           %Struct with all images
        IAll2 = struct('Image', []);          %struct with images from 2nd path
        Imono;                                %Matrix with all images in grayscale
        Imono2;                               %Matrix with all images in grayscale from 2nd path
        currentImage1Number =1;
        currentImage2Number =2;
        myFolder;
        theFiles;
        
        colourVariantChangedBefore = false;  %Indicates if colour variant has been chnged before, to not generate G+B again
        selectedVariant = 'RGB'
        IAllGB = struct('Image', []);           %Struct with all images converted in GB
        IAll2GB = struct('Image', []);          %struct with images from 2nd path converted in GB
        ImonoGB;                                %Matrix with all images in grayscale converted in GB
        Imono2GB;                               %Matrix with all images in grayscale from 2nd path converted in GB
        
        selectedDescriptor = 'SURF';
    end
    
    methods (Access = private)
        
        function I = rgb2gb(app, Image)
            I = Image;
            I(:,:,1) = 0;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseonepathButton
        function ChooseonepathButtonPushed(app, event)
            app.secondPathIsChoosed = false;
            % Specify the folder where the files live. 
app.myFolder = uigetdir('E:\studia\VI sem\Moje\PAZIG\PAzIG-Projekt\Sekwencja', 'Choose directory');                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isdir(app.myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(app.myFolder, '*.jpg'); % Change to whatever pattern you need.           %case do roznych wybranych rozszerzen
app.theFiles = dir(filePattern);
app.IAll(1:length(app.theFiles)) = struct('Image', []);
app.numberOfFiles = length(app.theFiles);

hWaitBar = waitbar(0,'Reading files...','Name', 'Please wait');
for k = 1: app.numberOfFiles
  
  file = fullfile(app.myFolder, app.theFiles(k).name);
  Image = imread(file);
  app.IAll(k).Image = Image;%Read the jth image
  filegray = rgb2gray(Image);
  app.Imono(:,:,k) = filegray;
  waitbar(k/app.numberOfFiles)
end 

delete(hWaitBar);
    app.I = app.IAll(1).Image;
    imshow(app.I,'Parent', app.UIAxes);
    app.I2 = app.IAll(1).Image;
    imshow(app.I2, 'Parent', app.UIAxes_2 );
  
  drawnow; % Force display to update immediately.
  
  formatSpec = '/%d';
    app.Label.Text = sprintf(formatSpec,app.numberOfFiles);
    app.Label_2.Text = sprintf(formatSpec,app.numberOfFiles);
        end

        % Value changed function: stimageEditField
        function stimageEditFieldValueChanged(app, event)
        value = app.stimageEditField.Value;

            if value > app.numberOfFiles || value < 1
                warndlg('Number is out of range.');
                return;
            end
            if value > app.currentImage2Number && app.secondPathIsChoosed == false
                warndlg('1st image must be befor 2nd image')
                return;
            else                
                app.I = app.IAll(value).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = value;
            end

        end

        % Value changed function: ndimageEditField
        function ndimageEditFieldValueChanged(app, event)
        value = app.ndimageEditField.Value;
        if app.secondPathIsChoosed == false
            if value > app.numberOfFiles || value < 1
                warndlg('Number is out of range.');
                return;
            end
            if value < app.currentImage1Number
                warndlg('2nd image must be after 1st image')
            else
                app.I2 = app.IAll(value).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = value;
            end
        elseif app.secondPathIsChoosed == true
            if value > app.numberOfFiles2 || value < 1
                warndlg('Number is out of range.');
                return;                        
            else
                app.I2 = app.IAll2(value).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = value;
            end                
        end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
        if app.secondPathIsChoosed ==false                          %version with one path
            if(app.currentImage2Number == app.numberOfFiles)
                
                app.I = app.IAll(1).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = 1;
                app.stimageEditField.Value = app.currentImage1Number;
                
                app.I2 = app.IAll(2).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = 2;
                app.ndimageEditField.Value = app.currentImage2Number;
                
            else
                app.I = app.IAll(app.currentImage1Number+1).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number=app.currentImage1Number+1;
                app.stimageEditField.Value = app.currentImage1Number;
                
                
                app.I2 = app.IAll(app.currentImage2Number+1).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = app.currentImage2Number + 1;
                app.ndimageEditField.Value = app.currentImage2Number;
            end
        elseif app.secondPathIsChoosed == true                          %version with two paths
            if app.currentImage2Number == app.numberOfFiles2
                
                app.I2 = app.IAll2(1).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = 1;
                app.ndimageEditField.Value = app.currentImage2Number;
                
            else
                app.I = app.IAll(app.currentImage1Number+1).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number=app.currentImage1Number+1;
                app.stimageEditField.Value = app.currentImage1Number;
            end
            if app.currentImage1Number == app.numberOfFiles
                app.I = app.IAll(1).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = 1;
                app.stimageEditField.Value = app.currentImage1Number;
            else                            
                app.I2 = app.IAll2(app.currentImage2Number+1).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = app.currentImage2Number + 1;
                app.ndimageEditField.Value = app.currentImage2Number;
            end                          
            
        end


        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
        if app.secondPathIsChoosed == false
            if(app.currentImage1Number == 1)
                
                app.I = app.IAll(app.numberOfFiles-1).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = app.numberOfFiles-1;
                app.stimageEditField.Value = app.currentImage1Number;
                
                
                app.I2 = app.IAll(app.numberOfFiles).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = app.numberOfFiles;
                app.ndimageEditField.Value = app.currentImage2Number;
            else
                
                app.I = app.IAll(app.currentImage1Number-1).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number=app.currentImage1Number-1;
                app.stimageEditField.Value = app.currentImage1Number;
                
                
                app.I2 = app.IAll(app.currentImage2Number-1).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = app.currentImage2Number-1;
                app.ndimageEditField.Value = app.currentImage2Number;
            end
        elseif app.secondPathIsChoosed == true
            if app.currentImage1Number == 1
                
                app.I = app.IAll(app.numberOfFiles).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number = app.numberOfFiles;
                app.stimageEditField.Value = app.currentImage1Number;
            else
                app.I = app.IAll(app.currentImage1Number-1).Image;
                imshow(app.I,'Parent', app.UIAxes);
                drawnow;
                app.currentImage1Number=app.currentImage1Number-1;
                app.stimageEditField.Value = app.currentImage1Number;
            end    
            if app.currentImage2Number == 1
                
                app.I2 = app.IAll2(app.numberOfFiles2).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = app.numberOfFiles2;
                app.ndimageEditField.Value = app.currentImage2Number;    
            else
                app.I2 = app.IAll2(app.currentImage2Number-1).Image;
                imshow(app.I2,'Parent', app.UIAxes_2);
                drawnow;
                app.currentImage2Number = app.currentImage2Number-1;
                app.ndimageEditField.Value = app.currentImage2Number;
                
            end
        end
        end

        % Selection changed function: DescriptorsButtonGroup
        function DescriptorsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DescriptorsButtonGroup.SelectedObject.Text;
            
            app.selectedDescriptor = selectedButton;                      
            
            Im1gray=app.Imono(:,:,app.currentImage1Number);
            Im2gray = rgb2gray(app.I2);
            
switch app.selectedDescriptor
    case 'SURF'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        points1 = detectSURFFeatures(Im1gray);
        points2 = detectSURFFeatures(Im2gray);
        
        strongest1 = points1.selectStrongest(5);
        strongest2 = points2.selectStrongest(5);
        
        plot(strongest1, app.UIAxes, 'showOrientation',true);
        plot(strongest2, app.UIAxes_2, 'showOrientation',true);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'HOG'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        [hog1,visualization1] = extractHOGFeatures(Im1gray,'CellSize',[60 60]);
        [hog2,visualization2] = extractHOGFeatures(Im2gray,'CellSize',[60 60]);
        
        plot(visualization1, app.UIAxes);
        plot(visualization2, app.UIAxes_2);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'BRISK'      
        points1 = detectBRISKFeatures(Im1gray);
        points2 = detectBRISKFeatures(Im2gray);
        
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);


        strongest1 = points1.selectStrongest(5);
        strongest2 = points2.selectStrongest(5);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        plot(strongest1, app.UIAxes); %'showOrientation',true
        plot(strongest2, app.UIAxes_2);
        % po tym pojawia sie okienko figure
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'FAST'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        points1 = detectFASTFeatures(Im1gray);
        points2 = detectFASTFeatures(Im2gray);
        
        strongest1 = points1.selectStrongest(5);
        strongest2 = points2.selectStrongest(5);
        
        plot(strongest1, app.UIAxes);
        plot(strongest2, app.UIAxes_2);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'ORB'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        points1 = detectORBFeatures(Im1gray);
        points2 = detectORBFeatures(Im2gray);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        plot(points1, app.UIAxes, 'showScale',false);
        plot(points2, app.UIAxes_2, 'showScale',false);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'MSER'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        regions1 = detectMSERFeatures(Im1gray);
        regions2 = detectMSERFeatures(Im2gray);
        
        [features1, valid_points1] = extractFeatures(Im1gray, regions1,'Upright',true);
        [features2, valid_points2] = extractFeatures(Im2gray, regions2, "Upright",true);
        
        plot(valid_points1, app.UIAxes,'showOrientation',true);
        plot(valid_points2,app.UIAxes_2,'showOrientation',true);
        
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    case 'HARRIS'
        imshow(app.I,'Parent', app.UIAxes);
        imshow(app.I2,'Parent', app.UIAxes_2);
        
        hold(app.UIAxes,"on");
        hold(app.UIAxes_2,"on");
        
        corners1 = detectHarrisFeatures(Im1gray);
        corners2 = detectHarrisFeatures(Im2gray);
        
        [features1, valid_corners1] = extractFeatures(Im1gray, corners1);
        [features2, valid_corners2] = extractFeatures(Im2gray, corners2);

        plot(valid_corners1, app.UIAxes);
        plot(valid_corners2, app.UIAxes_2);
        
       
        hold(app.UIAxes,"off");
        hold(app.UIAxes_2,"off");
    otherwise
        disp('other value')
end
        end

        % Button pushed function: ChoosetwopathsButton
        function ChoosetwopathsButtonPushed(app, event)
app.secondPathIsChoosed = true;     %user            
app.myFolder = uigetdir('E:\studia\VI sem\Moje\PAZIG\PAzIG-Projekt\Sekwencja', 'Choose directory');                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isdir(app.myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(app.myFolder, '*.jpg'); % Change to whatever pattern you need.           %case do roznych wybranych rozszerzen
app.theFiles = dir(filePattern);
app.IAll(1:length(app.theFiles)) = struct('Image', []);
app.numberOfFiles = length(app.theFiles);

hWaitBar = waitbar(0,'Reading files...','Name', 'Please wait');
for k = 1: app.numberOfFiles
  
  file = fullfile(app.myFolder, app.theFiles(k).name);
  Image = imread(file);
  app.IAll(k).Image = Image;%Read the jth image
  filegray = rgb2gray(Image);
  app.Imono(:,:,k) = filegray;
  waitbar(k/app.numberOfFiles)
end 

delete(hWaitBar);
    app.I = app.IAll(1).Image;
    imshow(app.I,'Parent', app.UIAxes);

% % % % % % % % % % % % % % % % % % % % % CHOOSING SECOND PATH % % % % % % % % % %
app.currentImage2Number = 1;                                                                                %update number for file from 2nd path
app.myFolder = uigetdir('E:\studia\VI sem\Moje\PAZIG\PAzIG-Projekt\Sekwencja', 'Choose directory');          %obsługa wyjątku kiedy nic nie wybierzemy??
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isfolder(app.myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(app.myFolder, '*.jpg');                                                              %case do roznych wybranych rozszerzen
app.theFiles = dir(filePattern);
app.IAll2(1:length(app.theFiles)) = struct('Image', []);
app.numberOfFiles2 = length(app.theFiles);

hWaitBar = waitbar(0,'Reading files...','Name', 'Please wait');
for k = 1: app.numberOfFiles2
  
  file = fullfile(app.myFolder, app.theFiles(k).name);
  Image = imread(file);
  app.IAll2(k).Image = Image;       %Read the kth image
  filegray = rgb2gray(Image);
  app.Imono2(:,:,k) = filegray;
  waitbar(k/app.numberOfFiles)      %update waitbar
end     
delete(hWaitBar);

    app.I2 = app.IAll2(1).Image;
    imshow(app.I2, 'Parent', app.UIAxes_2 );
  
  drawnow; % Force display to update immediately.
  
  app.ndimageEditField.Value = 1;
  formatSpec = '/%d';
    app.Label.Text = sprintf(formatSpec,app.numberOfFiles);
    app.Label_2.Text = sprintf(formatSpec,app.numberOfFiles2);
        end

        % Value changed function: ColormodelSwitch
        function ColormodelSwitchValueChanged(app, event)
            value = app.ColormodelSwitch.Value;
            app.selectedVariant = value;
            if app.selectedVariant == 'G+B'
                if app.colourVariantChangedBefore == false
                    app.colourVariantChangedBefore = true;
                    if app.secondPathIsChoosed == false
                        
                        hWaitBar = waitbar(0,'Creating files...','Name', 'Please wait');
                        for i = 1:1:app.numberOfFiles
                            Image = app.IAll(i).Image;
                            
                            IGB = rgb2gb(app, Image);
                            IGBmono = rgb2gray(IGB);
                            app.IAllGB(i).Image = IGB;
                            app.ImonoGB(i).Image = IGBmono;
                            waitbar(i/app.numberOfFiles)
                        end
                        delete(hWaitBar);
                        
                    else
                        hWaitBar = waitbar(0,'Creating files from first path...','Name', 'Please wait');
                        for i = 1:1:app.numberOfFiles
                            Image = app.IAll(i).Image;
                            
                            IGB = rgb2gb(app, Image);
                            IGBmono = rgb2gray(IGB);
                            app.IAllGB(i).Image = IGB;
                            app.ImonoGB(i).Image = IGBmono;
                            waitbar(i/app.numberOfFiles);
                        end
                        delete(hWaitBar);
                        
                        hWaitBar = waitbar(0,'Creating files from second path...','Name', 'Please wait');
                        for i = 1:1:app.numberOfFiles2
                            Image = app.IAll2(i).Image;
                            
                            IGB = rgb2gb(app, Image);
                            IGBmono = rgb2gray(IGB);
                            app.IAll2GB(i).Image = IGB;
                            app.Imono2GB(i).Image = IGBmono;
                            waitbar(i/app.numberOfFiles);
                        end
                        delete(hWaitBar);
                    end
                    
                else
                    
                end
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageFeatureAnalysisUIFigure = uifigure('Visible', 'off');
            app.ImageFeatureAnalysisUIFigure.Name = 'Image Feature Analysis';
            app.CataloguePanel = uipanel(app.ImageFeatureAnalysisUIFigure);
            app.CataloguePanel.Title = 'Catalogue';
            app.ExtensionDropDownLabel = uilabel(app.CataloguePanel);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.CataloguePanel);
            app.ExtensionDropDown.Items = {'.tif', '.dcm', '.jpg', '.png'};
            app.ExtensionDropDown.Value = '.tif';
            app.ChooseonepathButton = uibutton(app.CataloguePanel, 'push');
            app.ChooseonepathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseonepathButtonPushed, true);
            app.ChooseonepathButton.Text = 'Choose one path';
            app.ChoosetwopathsButton = uibutton(app.CataloguePanel, 'push');
            app.ChoosetwopathsButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosetwopathsButtonPushed, true);
            app.ChoosetwopathsButton.Text = 'Choose two paths';
            app.ImagesPanel = uipanel(app.ImageFeatureAnalysisUIFigure);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes_2 = uiaxes(app.ImagesPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PreviousButton = uibutton(app.ImagesPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.ImagesPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.stimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.stimageEditFieldLabel.Text = '1st image';
            app.stimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.stimageEditField.ValueChangedFcn = createCallbackFcn(app, @stimageEditFieldValueChanged, true);
            app.stimageEditField.Value = 1;
            app.ndimageEditFieldLabel = uilabel(app.ImagesPanel);
            app.ndimageEditFieldLabel.Text = '2nd image';
            app.ndimageEditField = uieditfield(app.ImagesPanel, 'numeric');
            app.ndimageEditField.ValueChangedFcn = createCallbackFcn(app, @ndimageEditFieldValueChanged, true);
            app.ndimageEditField.Value = 2;
            app.Label = uilabel(app.ImagesPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.ImagesPanel);
            app.Label_2.Text = '';
            app.OptionsPanel = uipanel(app.ImageFeatureAnalysisUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.DescriptorsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DescriptorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DescriptorsButtonGroupSelectionChanged, true);
            app.DescriptorsButtonGroup.Title = 'Descriptors';
            app.SURFButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.SURFButton.Text = 'SURF';
            app.SURFButton.Value = true;
            app.BRISKButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.BRISKButton.Text = 'BRISK';
            app.HOGButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HOGButton.Text = 'HOG';
            app.FASTButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.FASTButton.Text = 'FAST';
            app.ORBButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.ORBButton.Text = 'ORB';
            app.MSERButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.MSERButton.Text = 'MSER';
            app.HARRISButton = uitogglebutton(app.DescriptorsButtonGroup);
            app.HARRISButton.Text = 'HARRIS';
            app.ColormodelSwitchLabel = uilabel(app.OptionsPanel);
            app.ColormodelSwitchLabel.Text = 'Color model';
            app.ColormodelSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.ColormodelSwitch.Items = {'RGB', 'G+B'};
            app.ColormodelSwitch.ValueChangedFcn = createCallbackFcn(app, @ColormodelSwitchValueChanged, true);
            app.ColormodelSwitch.Value = 'RGB';
            app.SimilaritythresholdEditFieldLabel = uilabel(app.OptionsPanel);
            app.SimilaritythresholdEditFieldLabel.Text = 'Similarity threshold ';
            app.SimilaritythresholdEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.CompareButton = uibutton(app.OptionsPanel, 'push');
            app.CompareButton.Text = 'Compare';
            app.SaveButton = uibutton(app.OptionsPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.Slider = uislider(app.OptionsPanel);
            app.Slider.Limits = [0 1];
            app.GeneratereportButton = uibutton(app.OptionsPanel, 'push');
            app.GeneratereportButton.Text = 'Generate report';
            % Show the figure after all components are created
            app.ImageFeatureAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFeaturesAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageFeatureAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageFeatureAnalysisUIFigure)
        end
    end
end